classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        YourWinningsLabel              matlab.ui.control.Label
        card5                          matlab.ui.control.Image
        dealer_card_1                  matlab.ui.control.Image
        PlayButton                     matlab.ui.control.Button
        error_message_window           matlab.ui.control.TextArea
        BettingAmountEditField         matlab.ui.control.NumericEditField
        BettingAmountEditFieldLabel    matlab.ui.control.Label
        MinimumBettingAmountEditField  matlab.ui.control.NumericEditField
        MinimumBettingAmountEditFieldLabel  matlab.ui.control.Label
        dealer_card4                   matlab.ui.control.Image
        dealer_card3                   matlab.ui.control.Image
        dealer_card2                   matlab.ui.control.Image
        DealersHandLabel               matlab.ui.control.Label
        YourHandLabel                  matlab.ui.control.Label
        card4                          matlab.ui.control.Image
        card3                          matlab.ui.control.Image
        card2                          matlab.ui.control.Image
        card1                          matlab.ui.control.Image
        StayButton                     matlab.ui.control.Button
        HitButton                      matlab.ui.control.Button
        WelcometoOnlineBlackjackLabel  matlab.ui.control.Label
    end

    % Represents all global variables
    properties (Access = private)
        deck_of_cards = [1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1];
        user_count = 0; % Count for the user's total 
        dealer_count = 0; % Count for the dealer's total
        hit_button_pressed = 0; % Counts number of times hit button is pressed
        user_image_list = ["card2", "card3", "card4", "card5"]; % List of the images where the user's card should be displayed
        dealer_image_list = ["dealer_card2", "dealer_card3", "dealer_card4"]; % List of the images where the dealer's card should be displayed
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MinimumBettingAmountEditField
        function MinimumBettingAmountEditFieldValueChanged2(app, event)
            set(app.HitButton,'Enable','off')
            set(app.StayButton,'Enable','off')
            set(app.PlayButton, 'Enable', 'off') % Sets all buttons to be off initially
            minimum_bet = app.MinimumBettingAmountEditField.Value;
            placed_bet = app.BettingAmountEditField.Value;
            if placed_bet < 0 % Enters the appropriate error message for this case
                app.error_message_window.Value = 'Please enter a valid betting amount before hitting play.  ';
            elseif placed_bet < minimum_bet % Enters the appropriate error message for this case
                app.error_message_window.Value = 'Please enter a value over the minimum betting amount before hitting play.';
            else % Allows user to continue
                app.error_message_window.Value = 'Please press the Play button. Good Luck!';
            end
        end

        % Value changed function: BettingAmountEditField
        function BettingAmountEditFieldValueChanged(app, event)
            placed_bet = app.BettingAmountEditField.Value; %This variable stores the value that the user enters for their betting amount. 
            minimum_bet = app.MinimumBettingAmountEditField.Value; % This variable stores the minimum bet amount that the user enters. 
            if placed_bet < 0 % Enters the appropriate error message for this case
                app.error_message_window.Value = 'Please enter a valid betting amount before hitting play.  ';
            elseif placed_bet < minimum_bet % Enters the appropriate error message for this case
                app.error_message_window.Value = 'Please enter a value over the minimum betting amount before hitting play.';
            elseif placed_bet == 0 % Enters the appropriate error message for this case
                app.error_message_window.Value = 'Please enter a valid betting amount over 0. ';
            else
                app.error_message_window.Value = 'Please press the Play button. Good Luck!';
            end % Allows user to continue by enabling the play button
            if strcmp(app.error_message_window.Value, 'Please press the Play button. Good Luck!') == 1 % Checks to see if the user has entered the appropriate values by comparing it to the error message window. 
                set(app.PlayButton, 'Enable', 'on')
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)

            [value, suit] = size(app.deck_of_cards); % Value is the index of each card and suit is the predetermined number of each card
            card_in_deck = 0; % Sets variable to 0 so that while loop can check for available card
            while card_in_deck == 0 % This logic is implemented every time the card in deck is not available
                row_index = randi(value, 1, 1); % Represents a random value from the 13 rows in the deck of cards matrix. 
                column_index = randi(suit, 1, 1); % Represents a random suit from the 4 columns in the deck of cards matrix. 
                card_in_deck = app.deck_of_cards(row_index, column_index); % Accesses that card element fron the deck of cards matrix. 
            end
            filename = strcat('C:\Users\prami\OneDrive\Desktop\CLPS_0950\CLPS_0950_project\', num2str(row_index), '_', num2str(column_index), '.png'); % Codes for accessing the card png for the card chosen above
            app.card1.ImageSource = filename; %Sets the first image equal to the the first card chosen
            if row_index >= 10 % All face cards have a value of 10
                app.user_count = app.user_count + 10;
            else %Every other card has its appropriate value
                app.user_count = app.user_count + row_index;
            end
            app.deck_of_cards(row_index, column_index) = 0; % Sets the card that was taken from the deck and removes it from the virtual deck
            card_in_deck = 0;
            while card_in_deck == 0 % Checks for another value of the card until it reaches an available card
                row_index = randi(value, 1, 1);
                column_index = randi(suit, 1, 1);
                card_in_deck = app.deck_of_cards(row_index, column_index);
            end
            filename = strcat('C:\Users\prami\OneDrive\Desktop\CLPS_0950\CLPS_0950_project\', num2str(row_index), '_', num2str(column_index), '.png'); % Codes for accessing the card png for the card chosen above
            app.dealer_card_1.ImageSource = filename; % Sets dealers card equal to the deck of card accessed
            app.deck_of_cards(row_index, column_index) = 0; 
            if row_index >= 10 % All face cards have a value of 10
                app.dealer_count = app.dealer_count + 10;
            else %Every other card has its appropriate value
                app.dealer_count = app.dealer_count + row_index;
            end
            set(app.PlayButton,'Enable','off')
            set(app.HitButton,'Enable','on')
            set(app.StayButton,'Enable','on') % Once the user has entered valid inputs they are allowed to hit and stay. 
        end

        % Button pushed function: HitButton
        function HitButtonPushed(app, event)
            app.hit_button_pressed = app.hit_button_pressed + 1; % Count for the number of times hit is pressed to acces image index matrix
            placed_bet = app.BettingAmountEditField.Value; % User's input in placed bet amount edit field
            [value, suit] = size(app.deck_of_cards); 
            card_in_deck = 0; % Default value for card in deck is 0
            while card_in_deck == 0 % This logic is implemented every time the card in deck is not available
                row_index = randi(value, 1, 1); % Picks random card from 13 rows
                column_index = randi(suit, 1, 1); % Picks random suit from 4 columns
                card_in_deck = app.deck_of_cards(row_index, column_index); % Accesses card from the deck
            end
            filename = strcat('C:\Users\prami\OneDrive\Desktop\CLPS_0950\CLPS_0950_project\', num2str(row_index), '_', num2str(column_index), '.png'); % Codes for accessing the card png for the card chosen above
            app.(app.user_image_list(app.hit_button_pressed)).ImageSource= filename;
            if row_index >= 10 % Face cards have a value of 10
                app.user_count = app.user_count + 10;
            else % All other cards have their normal value
                app.user_count = app.user_count + row_index;
            end
            app.deck_of_cards(row_index, column_index) = 0;
            if app.user_count > 21 % Disables every button once the game is over. 
                    app.error_message_window.Value = 'You went over 21; therefore, you lost the game :(';
                    app.YourWinningsLabel.Text = strcat('Your Winnings: ', ' ', num2str(-(placed_bet)));
                    set(app.HitButton,'Enable','off')
                    set(app.StayButton,'Enable','off')
                    set(app.PlayButton, 'Enable', 'off')
            elseif app.user_count == 21  % Disables every button once the game is over.
                    app.error_message_window.Value = 'Congratulations, you got a Blackjack! ';
                    app.YourWinningsLabel.Text = strcat('Your Winnings: ', ' ', num2str(1.5 * placed_bet));
                    set(app.HitButton,'Enable','off')
                    set(app.StayButton,'Enable','off')
                    set(app.PlayButton, 'Enable', 'off')
            end
        end

        % Button pushed function: StayButton
        function StayButtonPushed(app, event)
            [value, suit] = size(app.deck_of_cards);
            placed_bet = app.BettingAmountEditField.Value;
            dealer_card_count = 1;
            while app.dealer_count <= 16
                card_in_deck = 0;
                while card_in_deck == 0
                    row_index = randi(value, 1, 1);
                    column_index = randi(suit, 1, 1);
                    card_in_deck = app.deck_of_cards(row_index, column_index);
                end
                filename = strcat('C:\Users\prami\OneDrive\Desktop\CLPS_0950\CLPS_0950_project\', num2str(row_index), '_', num2str(column_index), '.png');
                app.(app.dealer_image_list(dealer_card_count)).ImageSource= filename;
                if row_index >= 10 % Face cards have a value of 10
                    app.dealer_count = app.dealer_count + 10;
                else % All other cards have their normal index value
                    app.dealer_count = app.dealer_count + row_index;
                end
                dealer_card_count = dealer_card_count + 1; % Image index for dealer's cards. 
            end
            if app.dealer_count > 21 && app.user_count < 21  % Checks what amount the user should have won based on user count and dealer count. Disables every button once the game is over.
                app.YourWinningsLabel.Text = strcat('Your Winnings: ', " ", num2str(2 * placed_bet));
                app.error_message_window.Value = 'Congratulations! You beat the dealer!';
                set(app.HitButton,'Enable','off')
                set(app.StayButton,'Enable','off')
                set(app.PlayButton, 'Enable', 'off')
            elseif app.dealer_count >= 17
                if app.user_count > 21  % Checks what amount the user should have won based on user count and dealer count. Disables every button once the game is over.
                    app.error_message_window.Value = 'You went over 21; therefore, you lost the game :(';
                    app.YourWinningsLabel.Text = strcat('Your Winnings: '," ", num2str(-(placed_bet)));
                    set(app.HitButton,'Enable','off')
                    set(app.StayButton,'Enable','off')
                    set(app.PlayButton, 'Enable', 'off')
                elseif app.user_count == 21 % Checks what amount the user should have won based on user count and dealer count. Disables every button once the game is over. 
                    app.error_message_window.Value = 'Congratulations, you got a Blackjack! ';
                    app.YourWinningsLabel.Text = strcat('Your Winnings: ', " " , num2str(1.5 * placed_bet));
                    set(app.HitButton,'Enable','off')
                    set(app.StayButton,'Enable','off')
                    set(app.PlayButton, 'Enable', 'off')
                elseif app.user_count < 21  % Checks what amount the user should have won based on user count and dealer count. Disables every button once the game is over.
                    if app.user_count > app.dealer_count % Checks what amount the user should have won based on user count and dealer count. Disables every button once the game is over.
                        app.error_message_window.Value = 'Congratulations! You got a higher score than the dealer!';
                        app.YourWinningsLabel.Text = strcat('Your Winnings: ', " ", num2str(2 * placed_bet));
                        set(app.HitButton,'Enable','off')
                        set(app.StayButton,'Enable','off')
                        set(app.PlayButton, 'Enable', 'off')
                    elseif app.user_count == app.dealer_count % Checks what amount the user should have won based on user count and dealer count. Disables every button once the game is over.
                        app.error_message_window.Value = 'You tied with the dealer. '; 
                        app.YourWinningsLabel.Text = strcat('Your Winnings: ', " ", num2str(0));
                        set(app.HitButton,'Enable','off')
                        set(app.StayButton,'Enable','off')
                        set(app.PlayButton, 'Enable', 'off')
                    elseif app.user_count < app.dealer_count % Checks what amount the user should have won based on user count and dealer count. Disables every button once the game is over.
                        app.error_message_window.Value = 'Sorry, the dealer had a higher score than you :(';
                        app.YourWinningsLabel.Text = strcat('Your Winnings: ', " ", num2str(-(placed_bet)));
                        set(app.HitButton,'Enable','off')
                        set(app.StayButton,'Enable','off')
                        set(app.PlayButton, 'Enable', 'off')
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcometoOnlineBlackjackLabel = uilabel(app.UIFigure);
            app.WelcometoOnlineBlackjackLabel.Text = 'Welcome to Online Blackjack!';
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @HitButtonPushed, true);
            app.HitButton.Enable = 'off';
            app.HitButton.Text = 'Hit';
            app.StayButton = uibutton(app.UIFigure, 'push');
            app.StayButton.ButtonPushedFcn = createCallbackFcn(app, @StayButtonPushed, true);
            app.StayButton.Enable = 'off';
            app.StayButton.Text = 'Stay';
            app.card1 = uiimage(app.UIFigure);
            app.card1.ImageSource = fullfile(pathToMLAPP, 'card_1.png');
            app.card2 = uiimage(app.UIFigure);
            app.card2.ImageSource = fullfile(pathToMLAPP, 'card_2.png');
            app.card3 = uiimage(app.UIFigure);
            app.card3.ImageSource = fullfile(pathToMLAPP, 'card_3.png');
            app.card4 = uiimage(app.UIFigure);
            app.card4.ImageSource = fullfile(pathToMLAPP, 'card_4.png');
            app.YourHandLabel = uilabel(app.UIFigure);
            app.YourHandLabel.Text = 'Your Hand';
            app.DealersHandLabel = uilabel(app.UIFigure);
            app.DealersHandLabel.Text = 'Dealer''s Hand';
            app.dealer_card2 = uiimage(app.UIFigure);
            app.dealer_card2.ImageSource = fullfile(pathToMLAPP, 'dealer_card2.png');
            app.dealer_card3 = uiimage(app.UIFigure);
            app.dealer_card3.ImageSource = fullfile(pathToMLAPP, 'dealer_card3.png');
            app.dealer_card4 = uiimage(app.UIFigure);
            app.dealer_card4.ImageSource = fullfile(pathToMLAPP, 'dealer_card4.png');
            app.MinimumBettingAmountEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumBettingAmountEditFieldLabel.Text = 'Minimum Betting Amount';
            app.MinimumBettingAmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumBettingAmountEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumBettingAmountEditFieldValueChanged2, true);
            app.BettingAmountEditFieldLabel = uilabel(app.UIFigure);
            app.BettingAmountEditFieldLabel.Text = 'Betting Amount';
            app.BettingAmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.BettingAmountEditField.ValueChangedFcn = createCallbackFcn(app, @BettingAmountEditFieldValueChanged, true);
            app.error_message_window = uitextarea(app.UIFigure);
            app.error_message_window.Value = {'Good Luck!'};
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Text = 'Play';
            app.dealer_card_1 = uiimage(app.UIFigure);
            app.dealer_card_1.ImageSource = fullfile(pathToMLAPP, 'dealer_card1.png');
            app.card5 = uiimage(app.UIFigure);
            app.card5.ImageSource = fullfile(pathToMLAPP, 'card_5.png');
            app.YourWinningsLabel = uilabel(app.UIFigure);
            app.YourWinningsLabel.Text = 'Your Winnings: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end